classdef FlickerMasterTask < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainWindow                      matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        PhaseEditField                  matlab.ui.control.EditField
        PhaseEditFieldLabel             matlab.ui.control.Label
        PulseDurationmsEditField        matlab.ui.control.NumericEditField
        PulseDurationmsEditFieldLabel   matlab.ui.control.Label
        TaskDropDown                    matlab.ui.control.DropDown
        TaskDropDownLabel               matlab.ui.control.Label
        CommentEditField                matlab.ui.control.EditField
        CommentLabel                    matlab.ui.control.Label
        LogcommentButton                matlab.ui.control.Button
        MeasureBrightnessandVolumeButton  matlab.ui.control.Button
        TestOccluded                    matlab.ui.control.Button
        MasterTaskversionEditField      matlab.ui.control.EditField
        MasterTaskversionLabel          matlab.ui.control.Label
        ChangeOutputFolder_Button       matlab.ui.control.Button
        Outputfolder_EditField          matlab.ui.control.EditField
        OutputFolderLabel               matlab.ui.control.Label
        ChangeFlickerParameters_Button  matlab.ui.control.Button
        HardwareSetupLabel              matlab.ui.control.Label
        Testpulses_Button               matlab.ui.control.Button
        PlatformEditField               matlab.ui.control.EditField
        PlatformEditFieldLabel          matlab.ui.control.Label
        NIDAQdetected_EditField         matlab.ui.control.NumericEditField
        NIDAQdetectedEditFieldLabel     matlab.ui.control.Label
        NIDAQsamplerate_EditField       matlab.ui.control.NumericEditField
        NIDAQsamplerateEditFieldLabel   matlab.ui.control.Label
        FlickerTypeofburst_EditField    matlab.ui.control.EditField
        TypeofburstEditFieldLabel       matlab.ui.control.Label
        SoundFrequency_EditField        matlab.ui.control.NumericEditField
        SoundFrequencyEditFieldLabel    matlab.ui.control.Label
        FlickerDutycycleSound_EditField  matlab.ui.control.NumericEditField
        DutycycleSoundEditFieldLabel    matlab.ui.control.Label
        FlickerDutycycleLED_EditField   matlab.ui.control.NumericEditField
        DutycycleLEDEditFieldLabel      matlab.ui.control.Label
        SubjectID_EditField             matlab.ui.control.EditField
        SubjectIDEditFieldLabel         matlab.ui.control.Label
        Finalizedoptions_Button         matlab.ui.control.Button
        RunTask_Button                  matlab.ui.control.Button
        TestforSafety_Button            matlab.ui.control.Button
        TestforComfort_Button           matlab.ui.control.Button
        syncPulseConnected_Checkbox     matlab.ui.control.CheckBox
        NIDAQConnectedRefresh_Button    matlab.ui.control.Button
        ParametersLabel                 matlab.ui.control.Label
    end

    properties (Access = public)
        %hardware:
        Platform %windows or mac?
        NIDAQDeviceConnected %whether NIDAQDevice is connected
        
        %software:
        TaskName %the name of the task that is going to run for this experiment session
        MasterTaskVersion='1.3.0' %version of the master task
        
        SubjectID='FL###' %subject ID
        
        %general signal parameters (need those for all types of tasks)
        FlickerDutyCycleLED=0.5
        FlickerDutyCycleSound=0.5
        FlickerTypeOfBurst='ramp_round'
        SoundFreq=7000
        NIDAQSampleRate=250000
        
        %PulseEvokedPotentials task-specific parameters:
        PulseDuration=0.0125; %in s; note: whole trial duration lasts 1s, i.e. PulseDuration + whatever is left in a second
        InterTrialInterval=0.5; %in s; note: this means that between each trial (lasting 1s), there will be a randomized, on average, InterTrialInterval
        NumberOfTrials_perCondition=200;
        
        %FlickerMemory task-specific parameters:
        Phase='';
        
        %FlickerFrequencyTask-specific parameters:
        TaskFrequencies=["R";"5.5Hz";"8Hz";"11Hz";"14Hz";"17Hz";"20Hz";...
                         "23Hz";"26Hz";"29Hz";"32Hz";"35Hz";"38Hz";"40Hz";...
                         "42Hz";"45Hz";"48Hz";"51Hz";"54Hz";"57Hz";"63Hz";...
                         "66Hz";"69Hz";"72Hz";"75Hz";"78Hz";"80Hz"];
        
        %FlickerResonantFrequencyTask- specific parameters:
        TaskFrequenciesRF=["3Hz-AV";"5.5Hz-AV";"10Hz-AV";"12Hz-AV";"17Hz-AV";"20Hz-AV";...
                         "23Hz-AV";"29Hz-AV";"34Hz-AV";"37Hz-AV";"40Hz-AV";"43Hz-AV";"48Hz-AV";...
                         "63Hz-AV";"77Hz-AV";"80Hz-AV";"83Hz-AV"];
        TaskResonantFrequency
        TaskControlFrequency
        
        TaskModality
        
        Parameters_to_save={'TaskName','MasterTaskVersion','SubjectID','FlickerDutyCycleLED','FlickerDutyCycleSound','FlickerTypeOfBurst','SoundFreq','NIDAQSampleRate'}; %parameters that will be saved in .mat and .log files
        
        Source_Signal %contains information about the signals that will be sent to the NIDAQ
        
        %describes both commands sent to NIDAQ, and conditions being
        %played; the row number of the condition or operation in this variable determines the duration of the pulse (pulse_duration=row_number*0.01s), to decode what happened for data analysis.
        %Note: we set maximum duration of digital session to be 1/3rd of a second, which means that can only have up to about 30 sync pulse codes
        SyncPulseCode=["test";... %these lines describe the operations
                       "startExperiment";...
                       "startComfortTest";...
                       "startSafetyTest";...
                       "startRunTask";...
                       "startOccluded";...
                       "startMeasure";...
                       "stop";...
                       "end";...
                       
                       "40Hz-AV";... %for measuring brightness and volume
                       "constant-AV"]; %
                 
        %NIDAQ sessions:
        NI_session_analog
        NI_session_digital
        digital_channel
        
        %output folder and log file:
        OutputFolder=pwd %the initial output folder is in the working directory
        FileID %will hold the link to log file
        Current_UserComment_Time %holds time when user started typing in comment box
        MatFileObj %holds link to mat file containing mat variable that holds information about experiment (for data analysis)
    end
    
    methods (Access = public)
        function changeMainAppState(app,on_off) %control whether user can use given buttons or not (this will be used when switching back and forth between main app and sub-apps)
            app.Testpulses_Button.Enable=on_off; %want to be able to test sync pulse whenever you are back to main app
            app.TestforComfort_Button.Enable = on_off;
            app.TestforSafety_Button.Enable = on_off;
            app.RunTask_Button.Enable=on_off;
            app.TestOccluded.Enable=on_off;
            app.MeasureBrightnessandVolumeButton.Enable=on_off;
        end
        
    end
    
    methods (Access = private)
        function initialButtonsInteraction(app,on_off) %to enable initializing buttons when opening the task, and disable them once done choosing parameters
            app.SubjectID_EditField.Enable=on_off;
            app.ChangeOutputFolder_Button.Enable=on_off;
            app.TaskDropDown.Enable=on_off;
            app.ChangeFlickerParameters_Button.Enable=on_off;
            app.NIDAQConnectedRefresh_Button.Enable=on_off;
            app.syncPulseConnected_Checkbox.Enable=on_off;
        end
        
        function answer=changeFlickerParameters(app)
            answer=inputdlg({'Flicker duty cycle LED: ','Flicker duty cycle sound: ','Sound frequency: '},'Change to flicker parameters',[1 50;1 50;1 50],{num2str(app.FlickerDutyCycleLED),num2str(app.FlickerDutyCycleSound),num2str(app.SoundFreq)}); %prompt user for flicker parameters
            if isempty(answer) || any(ismissing(answer)) %re-present prompt if the user did not put any input
                answer=changeFlickerParameters(app);
            else
                answer{1}=str2double(answer{1});
                answer{2}=str2double(answer{2});
                answer{3}=str2double(answer{3});
                if ~((answer{1}>=0 && answer{1}<=1) ... %if these conditions are not respected, re-present prompt
                        && (answer{2}>=0 && answer{2}<=1) ... %duty cycle should be between 0 and 1
                        && (answer{3}>=20 && answer{3}<=20000)) %sound frequency should be between 20Hz and 20kHz (human hearing range)
                    answer=changeFlickerParameters(app);
                end
            end
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %adjust gui position:
            main_screen_size=get(0,'ScreenSize'); %get screen size
            app.MainWindow.Position=[app.MainWindow.Position(3)/50 main_screen_size(4)/2-app.MainWindow.Position(4)/2 ...
                                                        app.MainWindow.Position(3) app.MainWindow.Position(4)]; %place app window in middle of vertical axis of the screen, to the left
            
            %identify if PC or mac, and initialize NIDAQ if PC (can't use NIDAQ on mac):
            if ispc
                app.Platform='Windows';
                app.NIDAQConnectedRefresh_Button.Enable=1; %enable the refresh button, in case forgot to plug in NIDAQ
                devices=daq.getDevices; %get list of NIDAQ devices connected to PC
                if ~isempty(devices) && any(strcmp(devices.ID,'Dev1')) %check if any NIDAQ devices are connected and if there are, check for whether specific device we want is connected
                    app.NIDAQDeviceConnected=1;
                    call_to_NIDAQ(app,'initialize'); %initialize NIDAQ sessions for the experiment
                elseif ~isempty(devices) 
                    warning('There is no device connected!');
                else
                    app.NIDAQDeviceConnected=0;
                end
            elseif ismac
                app.Platform='Mac';
                app.NIDAQDeviceConnected=0; %can't use NIDAQ on mac
            end
                                                    
            %display some labels:
            app.MasterTaskversionEditField.Value=app.MasterTaskVersion;
            app.SubjectID_EditField.Value=app.SubjectID;
            app.Outputfolder_EditField.Value=app.OutputFolder;
            app.PulseDurationmsEditField.Value=app.PulseDuration;
            app.FlickerDutycycleLED_EditField.Value=app.FlickerDutyCycleLED;
            app.FlickerDutycycleSound_EditField.Value=app.FlickerDutyCycleSound;
            app.SoundFrequency_EditField.Value=app.SoundFreq;
            app.FlickerTypeofburst_EditField.Value=app.FlickerTypeOfBurst;
            app.NIDAQsamplerate_EditField.Value=app.NIDAQSampleRate;
            app.PlatformEditField.Value=app.Platform;
            app.NIDAQdetected_EditField.Value=app.NIDAQDeviceConnected;
            
                                                    
            %randomly pick a random generation seed (will be used where randomness is needed in task):
            rng('shuffle'); %this ensures that everytime we run this function, we're using a different seed for generating random numbers %CORRECT TO PUT THIS HERE AND ONLY HERE?

            %enable all the buttons for user:
            initialButtonsInteraction(app,1);
            app.ChangeFlickerParameters_Button.Enable=0; %want user to first choose which task to run
            app.syncPulseConnected_Checkbox.Enable=0; %idem
            app.Testpulses_Button.Enable=1;
        end

        % Value changed function: SubjectID_EditField
        function SubjectID_EditFieldValueChanged(app, event)
            value = app.SubjectID_EditField.Value;
            if ~(length(value)==5 && strcmp(value(1),'F') && strcmp(value(2),'L') && all(ismember(value(3:5),'0123456789'))) %check whether inputed subjectID is correctly formatted
                warndlg('Invalid subjectID- please use the following format: FL###.');
                app.SubjectID_EditField.Value=app.SubjectID;
            else
                app.SubjectID=value;
            end
        end

        % Button pushed function: ChangeOutputFolder_Button
        function ChangeOutputFolder_ButtonPushed(app, event)
            app.OutputFolder=uigetdir; %show user directory, so can pick output folder
            app.Outputfolder_EditField.Value=app.OutputFolder; %show the path to new output folder
            figure(app.MainWindow); %select main window again
        end

        % Value changed function: TaskDropDown
        function TaskDropDownValueChanged(app, event)
            app.TaskDropDown.Enable=0;
            if ~strcmp(app.TaskDropDown.Value,'select') %if user actally selected a task name
                switch app.TaskDropDown.Value
                    case 'FlickerNeurophysTask'
                        app.SyncPulseCode=[app.SyncPulseCode;... %add needed conditions to sync pulse code
                                           "Baseline";... %these lines describe the conditions
                                           "R-V";"R-AV";"R-A";...
                                           "5.5Hz-V";"5.5Hz-AV";"5.5Hz-A";...
                                           "40Hz-V";"40Hz-A";...
                                           "80Hz-V";"80Hz-AV";"80Hz-A"];
                        app.PulseDurationmsEditField.Visible=0; %no need to show this parameter, as not part of the task
                        app.PhaseEditField.Visible=0;
                    case 'FlickerDurationTask'
                        app.SyncPulseCode=[app.SyncPulseCode;...
                                           "R-AV";"5.5Hz-AV";...
                                           "40Hz-V";"40Hz-A"]; %for occluded condition
                        app.PulseDurationmsEditField.Visible=0;
                        app.PhaseEditField.Visible=0;
                    case 'PulseEvokedPotentialsTask'
                        app.SyncPulseCode=[app.SyncPulseCode;...
                                           "V";"AV";"A"]; %conditions tested
                        app.Parameters_to_save=[app.Parameters_to_save,{'PulseDuration','InterTrialInterval','NumberOfTrials_perCondition'}]; %in case of this task, additional parameters to save in mat and log files
                        app.PhaseEditField.Visible=0;
                    case 'FlickerMemoryTask'
                        %figure out whether this is the study phase or
                        %1-day test:
                        if ~exist([app.OutputFolder,'/task-flickermem/sub-',app.SubjectID], 'dir')
                            app.Phase='Study';
                        elseif exist([app.OutputFolder,'/task-flickermem/sub-',app.SubjectID], 'dir') %check if a folder for this task and subject has already been created
                            app.Phase='1-dayTest';
                            app.TestforComfort_Button.Visible=0;
                            app.TestforSafety_Button.Visible=0;
                            app.RunTask_Button.Text='Run 1-day test phase';
                            app.TestOccluded.Visible=0;
                            app.MeasureBrightnessandVolumeButton.Visible=0;
                        end
                        app.SyncPulseCode=[app.SyncPulseCode;...
                                               "Baseline";"R-AV";"5.5Hz-AV";...
                                               "40Hz-V";"40Hz-A";... %for occluded condition 
                                               "picturePres"];
                        app.PhaseEditField.Value=app.Phase;
                        app.PulseDurationmsEditField.Visible=0;
                    case 'FlickerFrequencyTask'
                        modalities={'Visual','Audio'};
                        [indx,~] = listdlg('PromptString','Modality?','SelectionMode','single','ListString',modalities);
                        figure(app.MainWindow);
                        app.TaskModality=modalities{indx};
                        app.SyncPulseCode=[app.SyncPulseCode;... %add needed conditions to sync pulse code
                                           "Baseline";... %these lines describe the conditions
                                           strcat(app.TaskFrequencies,'-',app.TaskModality(1))];
                                       
                        app.Parameters_to_save=[app.Parameters_to_save,'TaskFrequencies','TaskModality'];
                        app.PulseDurationmsEditField.Visible=0; %no need to show this parameter, as not part of the task
                        app.PhaseEditField.Visible=0;
                    case 'FlickerFrequencySweepTask'
                        figure(app.MainWindow);
                        app.TaskModality={'Audiovisual'};
                        app.SyncPulseCode=[app.SyncPulseCode;... %add needed conditions to sync pulse code
                                           "Baseline";... %these lines describe the conditions
                                           app.TaskFrequenciesRF];
                        temp=find(app.SyncPulseCode=='40Hz-AV'); %deleting the extra sync pulse code for 40Hz-AV
                        app.SyncPulseCode(temp(1))=[];
                        

                        % 2x10s trials in random order of baseline and 17 tested frequencies (3-83Hz).
                        
                        % ask user for the SOZ channels to input

                        % Calculate power fold change in PSD in the SOZ channels > find min and max 
                        
                        % for control and test: TaskControlFrequency and
                        % TaskResonantFrequency: for now just set them to a
                        % value to prototype the next steps.

                        
                        app.Parameters_to_save=[app.Parameters_to_save,'TaskFrequenciesRF','TaskModality'];
                        app.PulseDurationmsEditField.Visible=0; %no need to show this parameter, as not part of the task
                        app.PhaseEditField.Visible=0;
                    
                    case 'FlickerResonantFrequencyTask'
                        figure(app.MainWindow);
                        app.TaskModality={'Audiovisual'};
                        app.SyncPulseCode=[app.SyncPulseCode;... %add needed conditions to sync pulse code
                                           "Baseline";... %these lines describe the conditions
                                           app.TaskFrequenciesRF];
                        temp=find(app.SyncPulseCode=='40Hz-AV'); %deleting the extra sync pulse code for 40Hz-AV
                        app.SyncPulseCode(temp(1))=[];
                                       
                        app.Parameters_to_save=[app.Parameters_to_save,'TaskFrequenciesRF','TaskModality'];
                        app.PulseDurationmsEditField.Visible=0; %no need to show this parameter, as not part of the task
                        app.PhaseEditField.Visible=0;
                    case 'FlickerDifferentDurationsTask'
                        app.SyncPulseCode=[app.SyncPulseCode;...
                                           "90 sec"; "180 sec"]; %for longer duration condition
                        app.PulseDurationmsEditField.Visible=1;
                        app.PhaseEditField.Visible=0;
                end
                
                app.TaskName=app.TaskDropDown.Value;
                app.ChangeOutputFolder_Button.Enable=0;
                app.ChangeFlickerParameters_Button.Enable=1;
                app.syncPulseConnected_Checkbox.Enable=1;
            else
                app.TaskDropDown.Enable=1;
            end
        end

        % Button pushed function: ChangeFlickerParameters_Button
        function ChangeFlickerParameters_ButtonPushed(app, event)
            answer=changeFlickerParameters(app); %ask user for flicker parameters desired
            
            %reassigned inputed flicker parameters:
            app.FlickerDutyCycleLED=answer{1};
            app.FlickerDutyCycleSound=answer{2};
            app.SoundFreq=answer{3};
            
            %re-label on main app:
            app.FlickerDutycycleLED_EditField.Value=app.FlickerDutyCycleLED;
            app.FlickerDutycycleSound_EditField.Value=app.FlickerDutyCycleSound;
            app.SoundFrequency_EditField.Value=app.SoundFreq;
        end

        % Button pushed function: NIDAQConnectedRefresh_Button
        function NIDAQConnectedRefresh_ButtonPushed(app, event)
            daqreset; %needed to add this so that daq.getDevices detects device if NIDAQ plugged in after start the task
            devices=daq.getDevices;
            if ~isempty(devices) && any(strcmp(devices.ID,'Dev1')) %if NIDAQ device connected, and is the one we need for the task
                app.NIDAQDeviceConnected=1;
                app.NIDAQdetected_EditField.Value=1;
                call_to_NIDAQ(app,'initialize');
            end
        end

        % Button pushed function: Testpulses_Button
        function Testpulses_ButtonPushed(app, event)
            call_to_NIDAQ(app,'test'); %test the sync pulse
        end

        % Value changed function: syncPulseConnected_Checkbox
        function syncPulseConnected_CheckboxValueChanged(app, event)
            if app.syncPulseConnected_Checkbox.Value
                app.Finalizedoptions_Button.Enable=1; %if user indicates they've correctly set up the sync pulse hardware, allow to finalize options
            end
        end

        % Button pushed function: Finalizedoptions_Button
        function Finalizedoptions_ButtonPushed(app, event)
            app.Finalizedoptions_Button.Enable=0;
            initialButtonsInteraction(app,0);
            app.Testpulses_Button.Enable=0;
            
            if isempty(app.OutputFolder) %if no output folder specified, ask user for path to output folder
                warndlg('Please select an output folder.');
                initialButtonsInteraction(app,1);
                app.Finalizedoptions_Button.Enable=1;
                app.Testpulses_Button.Enable=1;
            elseif ~(length(app.SubjectID)==5 && strcmp(app.SubjectID(1),'F') && strcmp(app.SubjectID(2),'L') && all(ismember(app.SubjectID(3:5),'0123456789'))) %check that subject ID satisfies nomenclature
                warndlg('Invalid subjectID- please use the following format: FL###.');
                initialButtonsInteraction(app,1);
                app.Finalizedoptions_Button.Enable=1;
                app.Testpulses_Button.Enable=1;
            else %good to go
                
                %set task label/code for folder/file names
                switch app.TaskName
                    case 'FlickerNeurophysTask'
                        task_label='flickerneuro';
                    case 'FlickerDurationTask'
                        task_label='flickerduration';
                    case 'PulseEvokedPotentialsTask'
                        task_label='spep';
                    case 'FlickerMemoryTask'
                        task_label='flickermem';
                    case 'FlickerFrequencyTask'
                        task_label='flickerfreq';
                    case 'FlickerFrequencySweepTask'
                        task_label='flickersweep';
                    case 'FlickerResonantFrequencyTask'
                        task_label='flickerrf';
                    case 'FlickerDifferentDurationsTask'
                        task_label='flickerDiffDuration';
                end
                
                %define output folder for this task and subject, and create such folder if it does not already exist:
                app.OutputFolder=[app.OutputFolder,'/task-',task_label,'/sub-',app.SubjectID]; %set output folder subdirectory
                if ~exist(app.OutputFolder, 'dir')
                    mkdir(app.OutputFolder);
                end
                app.Outputfolder_EditField.Value=app.OutputFolder; %label where the data is going to be saved
                
                %initialize log file (where description of the task, events
                %and user comments will be written, for easy user read), and mat structure (where similar information will be saved, for easy programmatic read):
                filename=[app.OutputFolder,'/sub-',app.SubjectID,'_stg-raw_task-',task_label,'_ses-##'];
                if strcmp(app.TaskName,'FlickerMemoryTask')
                    filename=[filename,'_phase-',app.Phase];
                end
                filename=[filename,'_desc-',char(strjoin(string(round(clock)),'-')),'_nat-beh']; %initialize filename for both files
                app.FileID=fopen([filename,'.log'],'w'); %create log file
                if ~(strcmp(app.TaskName,'FlickerMemoryTask') && strcmp(app.Phase,'1-dayTest')) %if we're not running the 1-day-test of the memory task, create a new mat variable
                    ExpDetails=struct(); %create structure for mat file
                    
                    %add experiment information we have so far in both log and mat files:
                    for i=1:length(app.Parameters_to_save)
                        ExpDetails.(app.Parameters_to_save{i})=app.(app.Parameters_to_save{i});
                        if strcmp(app.Parameters_to_save{i},'TaskFrequencies')
                            fprintf(app.FileID,'\n%s\t%s',app.Parameters_to_save{i},strjoin(app.(app.Parameters_to_save{i})'));
                        else
                            fprintf(app.FileID,'\n%s\t%s',app.Parameters_to_save{i},string(app.(app.Parameters_to_save{i})));
                        end
                    end
                    ExpDetails.SyncPulseCode=app.SyncPulseCode; %this is one extra piece of information that we put in mat file but not in log file
                    
                    %save mat structure and create a mat file object to be able to
                    %access it:
                    save([filename,'.mat'],'ExpDetails');
                else %fetch the mat structure that was created during the study phase
                    filename=dir([app.OutputFolder,'/sub-',app.SubjectID,'_stg-raw_task-flickermem_phase-Study_desc-*_nat-beh.mat']);
                    filename=[filename.folder,'/',filename.name(1:end-4)];
                end
                app.MatFileObj=matfile([filename,'.mat'],'Writable',true);
                
                %set up for using sub-tasks:
                call_to_NIDAQ(app,'startExperiment'); %indicate in recording that we are starting experiment
                fprintf(app.FileID,'\n%s\t%s',char(strjoin(string(clock),'-')),'Finalized setting up options for main app.');
                changeMainAppState(app,1); %allow user to select the sub-apps
                app.CommentLabel.Enable=1;
                app.CommentEditField.Enable=1; %enable the comment box (for user comments)
            end
        end

        % Value changing function: CommentEditField
        function CommentEditFieldValueChanging(app, event)
            if isempty(app.Current_UserComment_Time) %if no comment start time has been saved yet, get current time and unable logging of comment
                app.Current_UserComment_Time=clock;
                app.LogcommentButton.Enable=1;
            end
        end

        % Button pushed function: LogcommentButton
        function LogcommentButtonPushed(app, event)
            app.LogcommentButton.Enable=0;
            fprintf(app.FileID,'\n%s\t%s',char(strjoin(string(app.Current_UserComment_Time),'-')),strcat("User comment: ",string(app.CommentEditField.Value))); %add time of user comment, and user comment, to log file
            app.CommentEditField.Value=''; %clear comment area
            app.Current_UserComment_Time=[]; %clear comment start time that we held
        end

        % Button pushed function: TestforComfort_Button
        function TestforComfort_ButtonPushed(app, event)
            changeMainAppState(app,0); %disable ability to run sub-tasks
            switch app.TaskName
                case {'FlickerNeurophysTask','FlickerDurationTask',...
                        'FlickerMemoryTask','FlickerFrequencyTask',...
                        'FlickerFrequencySweepTask',...
                        'FlickerResonantFrequencyTask',...
                        'FlickerDifferentDurationsTask'} %comfort testing uses same sub-apps in these cases
                    ComfortTesting_Flicker(app);
                case 'PulseEvokedPotentialsTask'
                    ComfortTesting_Pulse(app);
            end
        end

        % Button pushed function: TestforSafety_Button
        function TestforSafety_ButtonPushed(app, event)
            changeMainAppState(app,0);
            switch app.TaskName
                case 'FlickerNeurophysTask'
                    SafetyTesting_Flicker(app,cellstr(string(1:12))); %enable all 12 buttons in sub-app
                case {'FlickerDurationTask','FlickerMemoryTask'}
                    SafetyTesting_Flicker(app,{'2','5','8'}); %only enable buttons corresponding to conditions we are going to run
                case 'PulseEvokedPotentialsTask'
                    SafetyTesting_Pulse(app);
                case 'FlickerFrequencyTask'
                    SafetyTesting_FlickerFrequency(app);
                case 'FlickerResonantFrequencyTask'
                    SafetyTesting_FlickerResonantFrequency(app);
                case 'FlickerDifferentDurationsTask'
                    SafetyTesting_FlickerDifferentDurations(app);
            end
        end

        % Button pushed function: RunTask_Button
        function RunTask_ButtonPushed(app, event)
            changeMainAppState(app,0);
            switch app.TaskName
                case 'FlickerNeurophysTask'
                    RunTask_FlickerNeurophys(app);
                case 'FlickerDurationTask'
                    RunTask_FlickerDuration(app);
                case 'PulseEvokedPotentialsTask'
                    RunTask_PulseEvokedPotentials(app);
                case {'FlickerMemoryTask'}
                    if strcmp(app.Phase,'Study')
                        RunTask_FlickerMemory(app);
                    elseif strcmp(app.Phase,'1-dayTest')
                        RunTask_FlickerMemoryTest(app);
                    end
                case 'FlickerFrequencyTask'
                    RunTask_FlickerFrequency(app);
                case 'FlickerFrequencySweepTask'
                    RunTask_FlickerFrequencySweep(app);
                case 'FlickerResonantFrequencyTask'
                    RunTask_FlickerResonantFrequency(app);
                case 'FlickerDifferentDurationsTask'
                    RunTask_FlickerDifferentDurations(app);
            end
        end

        % Button pushed function: TestOccluded
        function TestOccludedPushed(app, event)
            changeMainAppState(app,0);
            switch app.TaskName
                case {'FlickerNeurophysTask','FlickerFrequencyTask',...
                        'FlickerDurationTask','FlickerMemoryTask'...
                        'FlickerFrequencySweepTask',...
                        'FlickerResonantFrequencyTask',...
                        'FlickerDifferentDurationsTask'}
                    Occluded_Flicker(app);
                case 'PulseEvokedPotentialsTask'
                    Occluded_Pulse(app);
            end
        end

        % Button pushed function: MeasureBrightnessandVolumeButton
        function MeasureBrightnessandVolumeButtonPushed(app, event)
            changeMainAppState(app,0);
            Measure(app); %same measure sub-app used for all tasks
        end

        % Close request function: MainWindow
        function MainWindowCloseRequest(app, event)
            quitAnswer = uiconfirm(app.MainWindow,'Are you sure you want to quit?','Confirm close','Options',{'Yes','No'}); %confirm whether user wants to quit
            if strcmp(quitAnswer,'Yes') %if user answer was 'Yes', do the following:
                call_to_NIDAQ(app,'end'); %signal in recording that we're ending task
                if app.NIDAQDeviceConnected %reset the NIDAQ
                    daqreset;
                end
                if ~isempty(app.FileID) %if we've created a log file, indicate we're exiting from main app and close file
                    fprintf(app.FileID,'\n\n%s\t%s',char(strjoin(string(clock),'-')),'Exiting from main app.');
                    fclose(app.FileID);
                end
                delete(app) %WAY TO CLEANLY STOP ALL PROCESSES AND EXIT APP?
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainWindow = uifigure('Visible', 'off');
            app.MainWindow.Name = 'Flicker Master Task';
            app.MainWindow.CloseRequestFcn = createCallbackFcn(app, @MainWindowCloseRequest, true);
            app.GridLayout = uigridlayout(app.MainWindow);
            app.ParametersLabel = uilabel(app.GridLayout);
            app.ParametersLabel.Text = 'Parameters:';
            app.NIDAQConnectedRefresh_Button = uibutton(app.GridLayout, 'push');
            app.NIDAQConnectedRefresh_Button.ButtonPushedFcn = createCallbackFcn(app, @NIDAQConnectedRefresh_ButtonPushed, true);
            app.NIDAQConnectedRefresh_Button.Enable = 'off';
            app.NIDAQConnectedRefresh_Button.Text = 'Refresh';
            app.syncPulseConnected_Checkbox = uicheckbox(app.GridLayout);
            app.syncPulseConnected_Checkbox.ValueChangedFcn = createCallbackFcn(app, @syncPulseConnected_CheckboxValueChanged, true);
            app.syncPulseConnected_Checkbox.Enable = 'off';
            app.syncPulseConnected_Checkbox.Text = {'Sync pulse on clinical DC1 and BR ainp1?'; 'Visual output on clin DC3 and BR ainp2?'};
            app.TestforComfort_Button = uibutton(app.GridLayout, 'push');
            app.TestforComfort_Button.ButtonPushedFcn = createCallbackFcn(app, @TestforComfort_ButtonPushed, true);
            app.TestforComfort_Button.Enable = 'off';
            app.TestforComfort_Button.Text = '1. Test for Comfort';
            app.TestforSafety_Button = uibutton(app.GridLayout, 'push');
            app.TestforSafety_Button.ButtonPushedFcn = createCallbackFcn(app, @TestforSafety_ButtonPushed, true);
            app.TestforSafety_Button.Enable = 'off';
            app.TestforSafety_Button.Text = '2. Test for Safety';
            app.RunTask_Button = uibutton(app.GridLayout, 'push');
            app.RunTask_Button.ButtonPushedFcn = createCallbackFcn(app, @RunTask_ButtonPushed, true);
            app.RunTask_Button.Enable = 'off';
            app.RunTask_Button.Text = '3. Run Task ';
            app.Finalizedoptions_Button = uibutton(app.GridLayout, 'push');
            app.Finalizedoptions_Button.ButtonPushedFcn = createCallbackFcn(app, @Finalizedoptions_ButtonPushed, true);
            app.Finalizedoptions_Button.Enable = 'off';
            app.Finalizedoptions_Button.Text = 'Finalized options';
            app.SubjectIDEditFieldLabel = uilabel(app.GridLayout);
            app.SubjectIDEditFieldLabel.Text = 'SubjectID:';
            app.SubjectID_EditField = uieditfield(app.GridLayout, 'text');
            app.SubjectID_EditField.ValueChangedFcn = createCallbackFcn(app, @SubjectID_EditFieldValueChanged, true);
            app.SubjectID_EditField.Enable = 'off';
            app.DutycycleLEDEditFieldLabel = uilabel(app.GridLayout);
            app.DutycycleLEDEditFieldLabel.Text = 'Duty cycle LED:';
            app.FlickerDutycycleLED_EditField = uieditfield(app.GridLayout, 'numeric');
            app.FlickerDutycycleLED_EditField.Enable = 'off';
            app.DutycycleSoundEditFieldLabel = uilabel(app.GridLayout);
            app.DutycycleSoundEditFieldLabel.Text = 'Duty cycle Sound:';
            app.FlickerDutycycleSound_EditField = uieditfield(app.GridLayout, 'numeric');
            app.FlickerDutycycleSound_EditField.Enable = 'off';
            app.SoundFrequencyEditFieldLabel = uilabel(app.GridLayout);
            app.SoundFrequencyEditFieldLabel.Text = 'Sound Frequency:';
            app.SoundFrequency_EditField = uieditfield(app.GridLayout, 'numeric');
            app.SoundFrequency_EditField.Enable = 'off';
            app.TypeofburstEditFieldLabel = uilabel(app.GridLayout);
            app.TypeofburstEditFieldLabel.Text = 'Type of burst:';
            app.FlickerTypeofburst_EditField = uieditfield(app.GridLayout, 'text');
            app.FlickerTypeofburst_EditField.Enable = 'off';
            app.NIDAQsamplerateEditFieldLabel = uilabel(app.GridLayout);
            app.NIDAQsamplerateEditFieldLabel.Text = 'NIDAQ sample rate:';
            app.NIDAQsamplerate_EditField = uieditfield(app.GridLayout, 'numeric');
            app.NIDAQsamplerate_EditField.Enable = 'off';
            app.NIDAQdetectedEditFieldLabel = uilabel(app.GridLayout);
            app.NIDAQdetectedEditFieldLabel.Text = 'NIDAQ detected:';
            app.NIDAQdetected_EditField = uieditfield(app.GridLayout, 'numeric');
            app.NIDAQdetected_EditField.Enable = 'off';
            app.PlatformEditFieldLabel = uilabel(app.GridLayout);
            app.PlatformEditFieldLabel.Text = 'Platform:';
            app.PlatformEditField = uieditfield(app.GridLayout, 'text');
            app.PlatformEditField.Enable = 'off';
            app.Testpulses_Button = uibutton(app.GridLayout, 'push');
            app.Testpulses_Button.ButtonPushedFcn = createCallbackFcn(app, @Testpulses_ButtonPushed, true);
            app.Testpulses_Button.Enable = 'off';
            app.Testpulses_Button.Text = 'Test sync pulses';
            app.HardwareSetupLabel = uilabel(app.GridLayout);
            app.HardwareSetupLabel.Text = 'Hardware Setup:';
            app.ChangeFlickerParameters_Button = uibutton(app.GridLayout, 'push');
            app.ChangeFlickerParameters_Button.ButtonPushedFcn = createCallbackFcn(app, @ChangeFlickerParameters_ButtonPushed, true);
            app.ChangeFlickerParameters_Button.Enable = 'off';
            app.ChangeFlickerParameters_Button.Text = 'Change';
            app.OutputFolderLabel = uilabel(app.GridLayout);
            app.OutputFolderLabel.Text = 'Output Folder:';
            app.Outputfolder_EditField = uieditfield(app.GridLayout, 'text');
            app.Outputfolder_EditField.Enable = 'off';
            app.ChangeOutputFolder_Button = uibutton(app.GridLayout, 'push');
            app.ChangeOutputFolder_Button.ButtonPushedFcn = createCallbackFcn(app, @ChangeOutputFolder_ButtonPushed, true);
            app.ChangeOutputFolder_Button.Enable = 'off';
            app.ChangeOutputFolder_Button.Text = 'Change';
            app.MasterTaskversionLabel = uilabel(app.GridLayout);
            app.MasterTaskversionLabel.Text = 'Master Task version:';
            app.MasterTaskversionEditField = uieditfield(app.GridLayout, 'text');
            app.MasterTaskversionEditField.Editable = 'off';
            app.MasterTaskversionEditField.Enable = 'off';
            app.TestOccluded = uibutton(app.GridLayout, 'push');
            app.TestOccluded.ButtonPushedFcn = createCallbackFcn(app, @TestOccludedPushed, true);
            app.TestOccluded.Enable = 'off';
            app.TestOccluded.Text = '4. Test Occluded Condition';
            app.MeasureBrightnessandVolumeButton = uibutton(app.GridLayout, 'push');
            app.MeasureBrightnessandVolumeButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureBrightnessandVolumeButtonPushed, true);
            app.MeasureBrightnessandVolumeButton.Enable = 'off';
            app.MeasureBrightnessandVolumeButton.Text = {'5. Measure Brightness '; 'and Volume'};
            app.LogcommentButton = uibutton(app.GridLayout, 'push');
            app.LogcommentButton.ButtonPushedFcn = createCallbackFcn(app, @LogcommentButtonPushed, true);
            app.LogcommentButton.Enable = 'off';
            app.LogcommentButton.Text = 'Log comment';
            app.CommentLabel = uilabel(app.GridLayout);
            app.CommentLabel.Enable = 'off';
            app.CommentLabel.Text = 'Comment:';
            app.CommentEditField = uieditfield(app.GridLayout, 'text');
            app.CommentEditField.ValueChangingFcn = createCallbackFcn(app, @CommentEditFieldValueChanging, true);
            app.CommentEditField.Enable = 'off';
            app.TaskDropDownLabel = uilabel(app.GridLayout);
            app.TaskDropDownLabel.Text = 'Task:';
            app.TaskDropDown = uidropdown(app.GridLayout);
            app.TaskDropDown.Items = {'select', 'FlickerFrequencySweepTask', 'FlickerDifferentDurationsTask', 'FlickerResonantFrequencyTask', 'FlickerNeurophysTask', 'FlickerDurationTask', 'PulseEvokedPotentialsTask', 'FlickerMemoryTask', 'FlickerFrequencyTask'};
            app.TaskDropDown.ValueChangedFcn = createCallbackFcn(app, @TaskDropDownValueChanged, true);
            app.TaskDropDown.Enable = 'off';
            app.TaskDropDown.Value = 'select';
            app.PulseDurationmsEditFieldLabel = uilabel(app.GridLayout);
            app.PulseDurationmsEditFieldLabel.Text = 'Pulse Duration (ms):';
            app.PulseDurationmsEditField = uieditfield(app.GridLayout, 'numeric');
            app.PulseDurationmsEditField.Enable = 'off';
            app.PhaseEditFieldLabel = uilabel(app.GridLayout);
            app.PhaseEditFieldLabel.Text = 'Phase:';
            app.PhaseEditField = uieditfield(app.GridLayout, 'text');
            app.PhaseEditField.Enable = 'off';
            % Show the figure after all components are created
            app.MainWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FlickerMasterTask

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainWindow)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainWindow)
        end
    end
end