classdef ComfortTesting_Flicker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainWindow  matlab.ui.Figure
        GridLayout  matlab.ui.container.GridLayout
        info        matlab.ui.control.Label
        STOPButton  matlab.ui.control.Button
        Play40HzAV  matlab.ui.control.Button
    end

    properties (Access = public)
        CallingApp %so get access properties and functions from the calling app
        Source_Signal %signal that will be used to test for comfort
        Go %whether user wants to play 40Hz AV (i.e. did user press on start)?
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, FlickerMasterTask)
            app.CallingApp=FlickerMasterTask; %to be able to access properties and functions from calling app
            
            %adjust window position, to the right of the main app:
            app.MainWindow.Position(1)=app.CallingApp.MainWindow.Position(1)+app.CallingApp.MainWindow.Position(3);
            app.MainWindow.Position(2)=app.CallingApp.MainWindow.Position(2);
            
            %signal that we are starting this sub-app:
            fprintf(app.CallingApp.FileID,'\n\n%s\t%s',char(strjoin(string(clock),'-')),'Opening comfortTesting sub-app'); %indicate in log file that we are testing for comfort
            call_to_NIDAQ(app.CallingApp,'startComfortTest'); %indicate in recording that we are starting comfortTesting
            
            app.info.Text=strcat("Subject ",app.CallingApp.SubjectID,", testing for comfort"); %label information about this sub-app
            
            %produce 1min long 40Hz signal:
            app.Source_Signal.Hz40=produceSignals(60,40,0,app.CallingApp.FlickerDutyCycleLED,app.CallingApp.FlickerDutyCycleSound,app.CallingApp.SoundFreq,app.CallingApp.FlickerTypeOfBurst,app.CallingApp.NIDAQSampleRate); %create 1min of 40Hz AV signal
            
            %change label of "Play 40Hz-AV" button to specific modality in case we're doing FlickerFrequencyTask
            if strcmp(app.CallingApp.TaskName,'FlickerFrequencyTask')
                app.Play40HzAV.Text=['40Hz-' app.CallingApp.TaskModality(1)];
            end
            
            %now that we have set up everything, enable the PLAY button:
            app.Play40HzAV.Enable=1;
        end

        % Button pushed function: Play40HzAV
        function Play40HzAVPushed(app, event)
            app.STOPButton.Enable=1; %enable the STOP button
            app.Play40HzAV.Enable=0; %disable the PLAY button
            app.Go=1; %save in properties that the user has pressed the PLAY button (and not pressed the STOP button)
            
            while app.Go %while property Go is still true, keep playing the signal
                if strcmp(app.CallingApp.TaskName,'FlickerNeurophysTask')...
                        || strcmp(app.CallingApp.TaskName,'FlickerResonantFrequencyTask')...
                        || strcmp(app.CallingApp.TaskName,'lickerDifferentDurationsTask')
                    call_to_NIDAQ(app.CallingApp,'40Hz-AV',app.Source_Signal.Hz40);
                elseif strcmp(app.CallingApp.TaskName,'FlickerFrequencyTask')
                    signalData=app.Source_Signal.Hz40;
                    switch app.CallingApp.TaskModality
                        case 'Visual'
                            signalData(:,2)=0;
                            call_to_NIDAQ(app.CallingApp,'40Hz-V',signalData);
                        case 'Audio'
                            signalData(:,1)=0;
                            call_to_NIDAQ(app.CallingApp,'40Hz-A',signalData);
                    end
                end
            end
     
            app.STOPButton.Enable=0; %if Go is no longer true, disable the STOP button
            app.Play40HzAV.Enable=1; %and enable the PLAY button again
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.Go=0; %save in properties that user has pressed the STOP button
            call_to_NIDAQ(app.CallingApp,'stop'); %if press on STOP button, stop the signal from playing
        end

        % Close request function: MainWindow
        function MainWindowCloseRequest(app, event)
            exitSubApp(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainWindow = uifigure('Visible', 'off');
            app.MainWindow.Name = 'Comfort Testing';
            app.MainWindow.CloseRequestFcn = createCallbackFcn(app, @MainWindowCloseRequest, true);
            app.GridLayout = uigridlayout(app.MainWindow);
            app.Play40HzAV = uibutton(app.GridLayout, 'push');
            app.Play40HzAV.ButtonPushedFcn = createCallbackFcn(app, @Play40HzAVPushed, true);
            app.Play40HzAV.Enable = 'off';
            app.Play40HzAV.Text = 'Play 40Hz-AV';
            app.STOPButton = uibutton(app.GridLayout, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Enable = 'off';
            app.STOPButton.Text = 'STOP';
            app.info = uilabel(app.GridLayout);
            app.info.Text = '';
            % Show the figure after all components are created
            app.MainWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ComfortTesting_Flicker(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainWindow)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainWindow)
        end
    end
end