classdef Occluded_Flicker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainWindow                    matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        StopOccludedCondition_Button  matlab.ui.control.Button
        RunOccludedCondition_Button   matlab.ui.control.Button
        info                          matlab.ui.control.Label
    end

    
    properties (Access = public)
        CallingApp
        Source_Signal
        OccludedTask_Paused
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, FlickerMasterTask)
            app.CallingApp=FlickerMasterTask;
            
            %adjust window position:
            app.MainWindow.Position(1)=app.CallingApp.MainWindow.Position(1)+app.CallingApp.MainWindow.Position(3);
            app.MainWindow.Position(2)=app.CallingApp.MainWindow.Position(2);
            
            fprintf(app.CallingApp.FileID,'\n\n%s\t%s',char(strjoin(string(clock),'-')),'Opening Occluded sub-app'); %indicate in log file that we are doing the occluded task
            call_to_NIDAQ(app.CallingApp,'startOccluded'); %indicate in recording that we are starting the occluded task
            
            app.info.Text=strcat("Subject ",app.CallingApp.SubjectID,", occluded condition"); %label information about this sub-app
            
            app.Source_Signal=produceExperimentSignals('safety_testing',app.CallingApp.FlickerDutyCycleLED,app.CallingApp.FlickerDutyCycleSound,app.CallingApp.SoundFreq,app.CallingApp.FlickerTypeOfBurst,app.CallingApp.NIDAQSampleRate);

            %once everything is set up, can enable functionality:
            app.RunOccludedCondition_Button.Enable=1;
        end

        % Button pushed function: RunOccludedCondition_Button
        function RunOccludedCondition_ButtonPushed(app, event)
            app.RunOccludedCondition_Button.Enable=0;
            app.StopOccludedCondition_Button.Enable=1;
            app.OccludedTask_Paused=0; %re-initialize
            
            if strcmp(app.CallingApp.TaskName,'FlickerNeurophysTask')
                temp=[zeros(1,6) ones(1,6)]; %make a random set of A and V 10s trials (6 per modality)
                order_of_occluded_trials=temp(randperm(length(temp)));
                
                for i=1:length(order_of_occluded_trials)
                    if ~app.OccludedTask_Paused %if occluded task not paused, send next trial's worth of signal
                        if order_of_occluded_trials(i)
                            call_to_NIDAQ(app.CallingApp,'40Hz-V',app.Source_Signal.Hz40.*[1 0]);
                        elseif ~order_of_occluded_trials(i)
                            call_to_NIDAQ(app.CallingApp,'40Hz-A',app.Source_Signal.Hz40.*[0 1]);
                        end
                    elseif app.OccludedTask_Paused %if task was paused, break out of the loop
                       break;
                    end
                end
            elseif (strcmp(app.CallingApp.TaskName,'FlickerFrequencyTask') ||...
                    strcmp(app.CallingApp.TaskName,'FlickerResonantFrequencyTask') ||...
                    strcmp(app.CallingApp.TaskName,'FlickerDifferentDurationsTask') )
                for i=1:6 %6 trials of the same modality
                    if ~app.OccludedTask_Paused %if occluded task not paused, send next trial's worth of signal
                        switch app.CallingApp.TaskModality{1}
                            case 'Visual'
                                call_to_NIDAQ(app.CallingApp,'40Hz-V',app.Source_Signal.Hz40.*[1 0]);
                            case 'Audio'
                                call_to_NIDAQ(app.CallingApp,'40Hz-A',app.Source_Signal.Hz40.*[0 1]);
                            case 'Audiovisual'
                                call_to_NIDAQ(app.CallingApp,'40Hz-AV',app.Source_Signal.Hz40.*[1 1]);
                        end
                    elseif app.OccludedTask_Paused %if task was paused, break out of the loop
                        break;
                    end
                end
            end
            
            if ~app.OccludedTask_Paused %means we completed the task successfully
                fprintf(app.CallingApp.FileID,'\n%s\t%s',char(strjoin(string(clock),'-')),'Completed task');
                app.RunOccludedCondition_Button.Enable=0;
            else
                app.RunOccludedCondition_Button.Enable=1;
            end
            
            app.StopOccludedCondition_Button.Enable=0;
        end

        % Button pushed function: StopOccludedCondition_Button
        function StopOccludedCondition_ButtonPushed(app, event)
            app.OccludedTask_Paused=1;
            call_to_NIDAQ(app.CallingApp,'stop');
            fprintf(app.CallingApp.FileID,'\n%s\t%s',strjoin(string(round(clock)),'-'),'Occluded condition stopped');
        end

        % Close request function: MainWindow
        function MainWindowCloseRequest(app, event)
            exitSubApp(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainWindow = uifigure('Visible', 'off');
            app.MainWindow.Name = 'Occluded Condition';
            app.MainWindow.CloseRequestFcn = createCallbackFcn(app, @MainWindowCloseRequest, true);
            app.GridLayout = uigridlayout(app.MainWindow);
            app.info = uilabel(app.GridLayout);
            app.info.Text = '';
            app.RunOccludedCondition_Button = uibutton(app.GridLayout, 'push');
            app.RunOccludedCondition_Button.ButtonPushedFcn = createCallbackFcn(app, @RunOccludedCondition_ButtonPushed, true);
            app.RunOccludedCondition_Button.Enable = 'off';
            app.RunOccludedCondition_Button.Text = {'Run Occluded '; 'Condition'};
            app.StopOccludedCondition_Button = uibutton(app.GridLayout, 'push');
            app.StopOccludedCondition_Button.ButtonPushedFcn = createCallbackFcn(app, @StopOccludedCondition_ButtonPushed, true);
            app.StopOccludedCondition_Button.Enable = 'off';
            app.StopOccludedCondition_Button.Text = {'Stop Occluded '; 'Condition'};
            % Show the figure after all components are created
            app.MainWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Occluded_Flicker(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainWindow)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainWindow)
        end
    end
end