classdef RunTask_FlickerFrequencySweep < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainWindow                      matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        ExperimentnumberEditField       matlab.ui.control.NumericEditField
        ExperimentnumberEditFieldLabel  matlab.ui.control.Label
        PAUSEButton                     matlab.ui.control.Button
        STARTButton                     matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        Stimindicator                   matlab.ui.control.Label
        CurrentstimulusLabel            matlab.ui.control.Label
        TrialnumberEditField            matlab.ui.control.EditField
        TrialnumberEditFieldLabel       matlab.ui.control.Label
        SessionnumberEditField          matlab.ui.control.EditField
        SessionnumberEditFieldLabel     matlab.ui.control.Label
        info                            matlab.ui.control.Label
    end

    
    properties (Access = public)
        CallingApp
        Session_Number=0 %keeps track of which session we're on
        Trial_Number=0 %keeps track of which trial, within a given session, we're on
        Trial_Condition %condition of given trial
        Random_Trial_Number=0 %keeps track of which random condition trial we're on, overall
        Source_Signal
        StartedTask=0 %keeps track of whether we started task (i.e. finalized options)
        Task_Paused=0 %whether we paused task
        ExpNumber=1 %the experiment number- in most cases, will run this task only once per subject, but there could be cases where we run it more than once
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, FlickerMasterTask)
            app.CallingApp=FlickerMasterTask;
            
            %adjust window position:
            app.MainWindow.Position(1)=app.CallingApp.MainWindow.Position(1)+app.CallingApp.MainWindow.Position(3);
            app.MainWindow.Position(2)=app.CallingApp.MainWindow.Position(2);
            
            fprintf(app.CallingApp.FileID,'\n\n%s\t%s',char(strjoin(string(clock),'-')),'Opening RunTask sub-app'); %indicate in log file that we are starting the RunTask
            call_to_NIDAQ(app.CallingApp,'startRunTask'); %indicate in recording that we are starting the RunTask
            
            %create all the signals we'll need
            frequencies_tested=app.CallingApp.TaskFrequenciesRF;
            frequencies_tested=[frequencies_tested; '0']; %add baseline
            frequencies_tested=regexprep(frequencies_tested,'Hz-AV','');
            frequencies_tested=str2num(char(frequencies_tested))';
            % sourceSignal=produceExperimentSignals_FlickerFrequency(safetyTesting,duty_cycle_LED,duty_cycle_sound,sound_frequency,type_of_burst,sample_rate,frequencies_tested,modalities_tested,repeat_limit,stimPeriod_duration,postStimPeriod,numberOfTrials_perCondition,nber_sessions,trial_random)
            app.Source_Signal=produceExperimentSignals_FlickerFrequency(0,app.CallingApp.FlickerDutyCycleLED,app.CallingApp.FlickerDutyCycleSound,app.CallingApp.SoundFreq,app.CallingApp.FlickerTypeOfBurst,app.CallingApp.NIDAQSampleRate,frequencies_tested,{'AV'},[3 Inf],10,2,2,1,'distributed_random');
            
            %set some labels:
            app.info.Text=strcat("Subject ",app.CallingApp.SubjectID,", experiment ",string(app.ExpNumber));
            app.ExperimentnumberEditField.Value=app.ExpNumber;
            
            %once everything set up, can enable functionality:
            app.ExperimentnumberEditField.Enable=1; %this allows user to change the experiment number, in case have already tested subject before
            app.STARTButton.Enable=1;
        end

        % Value changed function: ExperimentnumberEditField
        function ExperimentnumberEditFieldValueChanged(app, event)
            app.ExpNumber=app.ExperimentnumberEditField.Value; %update ExpNumber if changed
            app.info.Text=strcat("Subject ",app.CallingApp.SubjectID,", experiment ",string(app.ExpNumber));
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.STARTButton.Enable=0;
            pause(0.5); %so that START button actually gets unabled
            
            if ~app.StartedTask %if this is the first time we're pressing START, means we have finalized the options
                app.StartedTask=1; %keep track that we have pressed START before
                app.ExperimentnumberEditField.Enable=0; %from now on, can't modify the experiment number
                
                %add additional information, including Source_Signal, to the structure in the mat file we saved
                temp=app.CallingApp.MatFileObj.ExpDetails; %fetch the structure
                temp.ExpNumber=app.ExpNumber; %add experiment number
                temp.Source_Signal=app.Source_Signal; %add the Source_Signal
                app.CallingApp.MatFileObj.ExpDetails=temp; %replace the structure in mat file with appended structure
                
                %write begining of task info in log file:
                fprintf(app.CallingApp.FileID,'\n%s\t%s','Experiment number:',string(app.ExpNumber));
                fprintf(app.CallingApp.FileID,'\n%s\t%s\t%s\t%s\t%s','Time','Session','Trial','Type of stim','Comments');
            end
            
            app.PAUSEButton.Enable=1; %so user can pause the task at any time
            
            if ~app.Task_Paused %determine the session number and trials to be played, in case the task was not paused
                app.Session_Number=app.Session_Number+1;
                app.SessionnumberEditField.Value=strcat(num2str(app.Session_Number)," (out of ",num2str(size(app.Source_Signal.trials_vector,2)),")"); %indicate which session number we're on
                trials=1:sum(app.Source_Signal.trials_vector(:,app.Session_Number)~=""); %vector of trial numbers we will run for this session
            elseif app.Task_Paused %if task was paused, need to determine how far to go back in trial numbers
                if app.Trial_Number==1 %if we were playing the first trial, just go back to trial 1
                    trials=1:sum(app.Source_Signal.trials_vector(:,app.Session_Number)~=""); %sometimes, last session has less trials than the others (because of overall number of trials), hence why we need to look for only trials without information (i.e. without "")
                else %otherwise, go back by one trial
                    trials=app.Trial_Number-1:sum(app.Source_Signal.trials_vector(:,app.Session_Number)~="");
                end
                app.Task_Paused=0; %re-initialize
            end
            
            for trial=trials
                if ~app.Task_Paused %if task was not paused, can proceed to the next trial
                    
                    app.Trial_Number=trial;
                    app.Trial_Condition=app.Source_Signal.trials_vector(trial,app.Session_Number);
                    app.TrialnumberEditField.Value=strcat(num2str(app.Trial_Number)," (out of ",num2str(max(trials)),")"); %indicate which trial we are on
                    
                    %figure out which signal we need to send to the NIDAQ:
                    if strcmp(app.Trial_Condition,'Baseline') %if this is a baseline trial
                        signalData=app.Source_Signal.Baseline;
                    else
                        trial_condition_dissected=strsplit(app.Trial_Condition,'-');
                        %determine frequency:
                        if strcmp(trial_condition_dissected(1),'R') %if this is a random flicker trial
                            total_trials_vector=reshape(app.Source_Signal.trials_vector,[1,numel(app.Source_Signal.trials_vector)]);
                            index_random_total_trials=find(contains(total_trials_vector,'R')); %gives indices of random trials out of whole experiment
                            index_total_trials=(app.Session_Number-1)*size(app.Source_Signal.trials_vector,1)+app.Trial_Number; %gives current trial number out of all experiment trials
                            index_current_random_total_trials=find(index_random_total_trials==index_total_trials); %gives the index of the random trial we are on (out of all random trials of the experiment)
                            signalData=app.Source_Signal.Rand{index_current_random_total_trials}; %to not repeat the same random flicker trial on subsequent trials
                        else %means this is a periodic stimulation trial
                            temp=regexprep(trial_condition_dissected(1),'Hz','');
                            temp=regexprep(temp,'\.','_'); %in case there is a dot
                            signalData=app.Source_Signal.(strcat('Hz',temp));
                        end
                        
                        %determine modality:
                        switch trial_condition_dissected(2)
                            case 'V'
                                signalData(:,2)=0;
                            case 'AV'
                                %nothing to do
                            case 'A'
                                signalData(:,1)=0;
                        end
                    end
                    
                    %add jittered interval of no stim at the end of the
                    %trial:
                    disp(['Overall trial number ' num2str((app.Session_Number-1)*size(app.Source_Signal.trials_vector,1)+trial)]);
                    signalData=[signalData;zeros(app.Source_Signal.added_intervals{(app.Session_Number-1)*size(app.Source_Signal.trials_vector,1)+trial},2)];
                    
                    %play the stim part of the trial
                    app.Stimindicator.Text=app.Source_Signal.trials_vector(trial,app.Session_Number);
                    app.Lamp.Color=[1 1 1];
                    fprintf(app.CallingApp.FileID,'\n%s\t%s\t%s\t%s',strjoin(string(round(clock)),'-'),string(app.Session_Number),string(app.Trial_Number),app.Trial_Condition);
                    call_to_NIDAQ(app.CallingApp,app.Trial_Condition,signalData);
                    app.Lamp.Color=[0 0 0];
                    
                    % 2x10s trials in random order of baseline and 17 tested frequencies (3-83Hz).
                    
                    % ask user for the SOZ channels to input

                    % Calculate power fold change in PSD in the SOZ channels > find min and max 
                    
                    % for control and test: TaskControlFrequency and
                    % TaskResonantFrequency: for now just set them to a
                    % value to prototype the next steps.

                elseif app.Task_Paused %if task was paused, break out of the loop (i.e. don't play next planned stim trial)
                    break;
                end
            end
            
            if ~app.Task_Paused %if task was not paused at this point, means we successfully went through whole session
                app.SessionnumberEditField.Value='';
                app.TrialnumberEditField.Value='';
                app.Stimindicator.Text=strcat(num2str(app.Session_Number),' done.'); %CORRECT?
                if app.Session_Number<6 %if we are at less than 6 sessions, means we have more sessions to go- enable START button again
                    app.STARTButton.Enable=1;
                elseif app.Session_Number==6 %means we're done with experiment
                    app.Stimindicator.Text=strcat('Experiment done.');
                    fprintf(app.CallingApp.FileID,'\n%s\t%s',char(strjoin(string(clock),'-')),'Completed task');
                end
                sound(generate_sound(500,200000),200000); %sound signal to the user that session is done; WILL THIS BE QUICK ENOUGH?
                msgbox('Done with session.','Done','help'); %visual signal to the user that session is done
            elseif app.Task_Paused %if task was paused, enable START button again
                app.STARTButton.Enable=1;
            end
            app.PAUSEButton.Enable=0;
        end

        % Button pushed function: PAUSEButton
        function PAUSEButtonPushed(app, event)
            app.Task_Paused=1;
            call_to_NIDAQ(app.CallingApp,'stop'); %first, stop whatever signal is playing on the NIDAQ
            fprintf(app.CallingApp.FileID,'\t%s','stimulus aborted early; task paused');
            app.Lamp.Color=[0 0 0];
            app.Stimindicator.Text='Task paused...';
        end

        % Close request function: MainWindow
        function MainWindowCloseRequest(app, event)
            exitSubApp(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainWindow = uifigure('Visible', 'off');
            app.MainWindow.Name = 'RunTask';
            app.MainWindow.CloseRequestFcn = createCallbackFcn(app, @MainWindowCloseRequest, true);
            app.GridLayout = uigridlayout(app.MainWindow);
            app.info = uilabel(app.GridLayout);
            app.info.Text = '';
            app.SessionnumberEditFieldLabel = uilabel(app.GridLayout);
            app.SessionnumberEditFieldLabel.Text = 'Session number:';
            app.SessionnumberEditField = uieditfield(app.GridLayout, 'text');
            app.SessionnumberEditField.Editable = 'off';
            app.TrialnumberEditFieldLabel = uilabel(app.GridLayout);
            app.TrialnumberEditFieldLabel.Text = 'Trial number:';
            app.TrialnumberEditField = uieditfield(app.GridLayout, 'text');
            app.TrialnumberEditField.Editable = 'off';
            app.CurrentstimulusLabel = uilabel(app.GridLayout);
            app.CurrentstimulusLabel.Text = 'Current stimulus:';
            app.Stimindicator = uilabel(app.GridLayout);
            app.Stimindicator.Text = '';
            app.Lamp = uilamp(app.GridLayout);
            app.STARTButton = uibutton(app.GridLayout, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Enable = 'off';
            app.STARTButton.Text = 'START';
            app.PAUSEButton = uibutton(app.GridLayout, 'push');
            app.PAUSEButton.ButtonPushedFcn = createCallbackFcn(app, @PAUSEButtonPushed, true);
            app.PAUSEButton.Enable = 'off';
            app.PAUSEButton.Text = 'PAUSE';
            app.ExperimentnumberEditFieldLabel = uilabel(app.GridLayout);
            app.ExperimentnumberEditFieldLabel.Text = 'Experiment number';
            app.ExperimentnumberEditField = uieditfield(app.GridLayout, 'numeric');
            app.ExperimentnumberEditField.ValueChangedFcn = createCallbackFcn(app, @ExperimentnumberEditFieldValueChanged, true);
            app.ExperimentnumberEditField.Enable = 'off';
            % Show the figure after all components are created
            app.MainWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RunTask_FlickerFrequencySweep(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainWindow)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainWindow)
        end
    end
end